classdef DispersionFittingApp_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        loaddataButton  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        files % stores file names
        filepath % stores full file path
        Energy_scale % stores energy scale generated from MM's file
        K_scale % stores momentum scale generated from MM's file
        data % stores the data with out the E and K scales
    end
    
    methods (Access = private)
        
        function [D, E, K] = readData(app,filepath)
            data1 = readmatrix(filepath{2}); %% to read the data file. Using readmatrix as we know the format of our file is floating and we want to use matrix form for ease of use later.
            E = data1(2:size(data1,1),1); %% scale generated from MM's code
            K = data1(1,2:size(data1,2)); %% scale generated from MM's code
            D = data1(2:size(data1,1),2:size(data1,2)); %% full data without the scales
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            selpath =uigetdir(' ');     %% to select the folder you want to work in
            app.files = dir(fullfile(selpath,'*.txt'));     %% to store all the file names with .txt extension
            app.filepath = fullfile({app.files.folder}, {app.files.name});     %% to generate filepaths for all the txt files in the folder
%             data1 = readmatrix(app.filepath{2}); %% to read the data file. Using readmatrix as we know the format of our file is floating and we want to use matrix form for ease of use later.
%             app.Energy_scale = data1(2:size(data1,1),1); %% scale generated from MM's code
%             app.K_scale = data1(1,2:size(data1,2)); %% scale generated from MM's code
%             app.data = data1(2:size(data1,1),2:size(data1,2)); %% full data without the scales
            [app.data,app.Energy_scale,app.K_scale] = readData(app, app.filepath);
            [A, h] =  contourf(app.UIAxes, app.K_scale, app.Energy_scale, app.data,50);
            set(h,'Linecolor', 'none');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'K')
            ylabel(app.UIAxes, 'Energy (eV)')
            zlabel(app.UIAxes, 'Z')
            app.loaddataButton = uibutton(app.UIFigure, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DispersionFittingApp_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end